classdef GUI_avatarAlfa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        BienvenidoaSRSLabel   matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout6           matlab.ui.container.GridLayout
        textFieldFecha        matlab.ui.control.Label
        textFieldPeso         matlab.ui.control.Label
        textFieldEdad         matlab.ui.control.Label
        textFieldPaciente     matlab.ui.control.Label
        FechaLabel            matlab.ui.control.Label
        PesokgLabel           matlab.ui.control.Label
        EdadLabel             matlab.ui.control.Label
        PacienteLabel         matlab.ui.control.Label
        GridLayout4           matlab.ui.container.GridLayout
        GrficasButton         matlab.ui.control.Button
        InformeButton         matlab.ui.control.Button
        VisualizarmovimientosButtonGroup  matlab.ui.container.ButtonGroup
        PosicinInicialButton  matlab.ui.control.Button
        BajadaButton          matlab.ui.control.Button
        SubidaButton          matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             global nombre apellido edad peso;
            %load datos.mat nombre apellido edad peso;

            app.textFieldPaciente.Text = strcat(nombre," ",apellido);
            app.textFieldEdad.Text = int2str(edad);
            app.textFieldPeso.Text = num2str(peso);

            formato = 'dd/mm/yy';
            app.textFieldFecha.Text = datestr(now,formato);

        end

        % Button pushed function: SubidaButton
        function SubidaButtonPushed(app, event)
main;


fm =10;
% Movimiento progresivo de subida
for ii = 1:size(y_cs,2)
    Rtorso = rotationMatrix(0,0,0);
    
cla(app.UIAxes);
tic
    torsoRotado = applyRotationNx3(torso,sum(torso)/8,Rtorso);
    torsoRotado1 = applyRotationNx3(torso1,sum(torso)/8,Rtorso);

    patch(app.UIAxes,'faces',f,'vertices',torsoRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes,'faces',f,'vertices',torsoRotado1,'FaceVertexCData',color,'FaceColor','flat');

    Rcadera = rotationMatrix(0,y_cs(ii),0);

    piernaSuperiorRotada1 = applyRotationNx3(applyRotationNx3(piernaSuperior1,...
        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
    piernaSuperiorRotada2 = applyRotationNx3(applyRotationNx3(piernaSuperior2,...
        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

    patch(app.UIAxes,'faces',f,'vertices',piernaSuperiorRotada1,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes,'faces',f,'vertices',piernaSuperiorRotada2,'FaceVertexCData',color,'FaceColor','flat');

%         for jj = 1:size(y_rs,2)
            Rrodilla = rotationMatrix(0,y_rs(ii),0);
            
            piernaInferiorRotada1 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna1,...
                sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
            piernaInferiorRotada2 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna2,...
                sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
            piernaInferiorRotada3 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna3,...
                sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

            patch(app.UIAxes,'faces',f,'vertices',piernaInferiorRotada1,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.UIAxes,'faces',f,'vertices',piernaInferiorRotada2,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.UIAxes,'faces',f,'vertices',piernaInferiorRotada3,'FaceVertexCData',color,'FaceColor','flat');
               
%                 for kk = 1:size(y_ts,2)
                    Rtobillo = rotationMatrix(0,y_ts(ii),0);

                    pieTalonRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(pieDTalon,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    piePuntaRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(pieDPunta,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoPulgarRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoGordo,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoIndiceRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoIndice,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoCorazonRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoCorazon,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoAnularRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoAnular,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoMeniqueRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoMenique,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

                    patch(app.UIAxes,'faces',f,'vertices',pieTalonRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.UIAxes,'faces',f,'vertices',piePuntaRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.UIAxes,'faces',f,'vertices',dedoPulgarRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.UIAxes,'faces',f,'vertices',dedoIndiceRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.UIAxes,'faces',f,'vertices',dedoCorazonRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.UIAxes,'faces',f,'vertices',dedoAnularRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.UIAxes,'faces',f,'vertices',dedoMeniqueRotado,'FaceVertexCData',color,'FaceColor','flat');
                    
                   
                    tiempo = toc;
                pause(1/fm-tiempo);
                    drawnow;
end
        end

        % Button pushed function: BajadaButton
        function BajadaButtonPushed(app, event)
main;

fm =10;
% Movimiento progresivo de subida
for ii = 1:size(y_cb,2)
    Rtorso = rotationMatrix(0,0,0);
    
cla(app.UIAxes);
tic
    torsoRotado = applyRotationNx3(torso,sum(torso)/8,Rtorso);
    torsoRotado1 = applyRotationNx3(torso1,sum(torso)/8,Rtorso);

    patch(app.UIAxes,'faces',f,'vertices',torsoRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes,'faces',f,'vertices',torsoRotado1,'FaceVertexCData',color,'FaceColor','flat');

    Rcadera = rotationMatrix(0,y_cb(ii),0);

    piernaSuperiorRotada1 = applyRotationNx3(applyRotationNx3(piernaSuperior1,...
        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
    piernaSuperiorRotada2 = applyRotationNx3(applyRotationNx3(piernaSuperior2,...
        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

    patch(app.UIAxes,'faces',f,'vertices',piernaSuperiorRotada1,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes,'faces',f,'vertices',piernaSuperiorRotada2,'FaceVertexCData',color,'FaceColor','flat');

%         for jj = 1:size(y_rs,2)
            Rrodilla = rotationMatrix(0,y_rb(ii),0);
            
            piernaInferiorRotada1 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna1,...
                sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
            piernaInferiorRotada2 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna2,...
                sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
            piernaInferiorRotada3 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna3,...
                sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

            patch(app.UIAxes,'faces',f,'vertices',piernaInferiorRotada1,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.UIAxes,'faces',f,'vertices',piernaInferiorRotada2,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.UIAxes,'faces',f,'vertices',piernaInferiorRotada3,'FaceVertexCData',color,'FaceColor','flat');
               
%                 for kk = 1:size(y_ts,2)
                    Rtobillo = rotationMatrix(0,y_tb(ii),0);

                    pieTalonRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(pieDTalon,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    piePuntaRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(pieDPunta,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoPulgarRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoGordo,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoIndiceRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoIndice,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoCorazonRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoCorazon,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoAnularRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoAnular,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                    dedoMeniqueRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoMenique,...
                        sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                        (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

                    patch(app.UIAxes,'faces',f,'vertices',pieTalonRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.UIAxes,'faces',f,'vertices',piePuntaRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.UIAxes,'faces',f,'vertices',dedoPulgarRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.UIAxes,'faces',f,'vertices',dedoIndiceRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.UIAxes,'faces',f,'vertices',dedoCorazonRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.UIAxes,'faces',f,'vertices',dedoAnularRotado,'FaceVertexCData',color,'FaceColor','flat');
                    patch(app.UIAxes,'faces',f,'vertices',dedoMeniqueRotado,'FaceVertexCData',color,'FaceColor','flat');
                    
                    
                    tiempo = toc;
                pause(1/fm-tiempo);
                    drawnow;
end      
        end

        % Button pushed function: PosicinInicialButton
        function PosicionInicialPushed(app, event)
main;
%--------------------------------------------------------------------------
%PIE
cla(app.UIAxes);
patch(app.UIAxes,'faces',f,'vertices',pieDTalon,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'vertices',pieDPunta,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'vertices',dedoGordo,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'vertices',dedoIndice,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'vertices',dedoCorazon,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'vertices',dedoAnular,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'vertices',dedoMenique,'FaceVertexCData',color,'FaceColor','flat');
%-----------------------------------------------------------------------------
%PIERNA INFERIOR
patch(app.UIAxes,'faces',f,'vertices',Pierna1,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'vertices',Pierna2,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'vertices',Pierna3,'FaceVertexCData',color,'FaceColor','flat');
%-----------------------------------------------------------------------------
%PIERNA SUPERIOR
patch(app.UIAxes,'faces',f,'vertices',piernaSuperior1,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'vertices',piernaSuperior2,'FaceVertexCData',color,'FaceColor','flat');
% Definicion de partes del cuerpo (lado izquierdo
patch(app.UIAxes,'faces',f,'vertices',torso1,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'vertices',torso,'FaceVertexCData',color,'FaceColor','flat');


        end

        % Button pushed function: GrficasButton
        function GraficasPushed(app, event)
            GUI_graficas
            app.delete
        end

        % Button pushed function: InformeButton
        function InformePushed(app, event)
            GUI_informe
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Simulación')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-130 100];
            app.UIAxes.YLim = [-10 60];
            app.UIAxes.ZLim = [-20 250];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.VisualizarmovimientosButtonGroup = uibuttongroup(app.GridLayout2);
            app.VisualizarmovimientosButtonGroup.Title = 'Visualizar movimientos';
            app.SubidaButton = uibutton(app.VisualizarmovimientosButtonGroup, 'push');
            app.SubidaButton.ButtonPushedFcn = createCallbackFcn(app, @SubidaButtonPushed, true);
            app.SubidaButton.Text = 'Subida';
            app.BajadaButton = uibutton(app.VisualizarmovimientosButtonGroup, 'push');
            app.BajadaButton.ButtonPushedFcn = createCallbackFcn(app, @BajadaButtonPushed, true);
            app.BajadaButton.Text = 'Bajada';
            app.PosicinInicialButton = uibutton(app.VisualizarmovimientosButtonGroup, 'push');
            app.PosicinInicialButton.ButtonPushedFcn = createCallbackFcn(app, @PosicionInicialPushed, true);
            app.PosicinInicialButton.Text = 'Posición Inicial';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.InformeButton = uibutton(app.GridLayout4, 'push');
            app.InformeButton.ButtonPushedFcn = createCallbackFcn(app, @InformePushed, true);
            app.InformeButton.Tag = 'buttonInforme';
            app.InformeButton.Text = 'Informe';
            app.GrficasButton = uibutton(app.GridLayout4, 'push');
            app.GrficasButton.ButtonPushedFcn = createCallbackFcn(app, @GraficasPushed, true);
            app.GrficasButton.Tag = 'buttonAvatar';
            app.GrficasButton.Text = 'Gráficas';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.PacienteLabel = uilabel(app.GridLayout6);
            app.PacienteLabel.Tag = 'labelPaciente';
            app.PacienteLabel.Text = 'Paciente:';
            app.EdadLabel = uilabel(app.GridLayout6);
            app.EdadLabel.Tag = 'labelEdad';
            app.EdadLabel.Text = 'Edad:';
            app.PesokgLabel = uilabel(app.GridLayout6);
            app.PesokgLabel.Tag = 'labelPeso';
            app.PesokgLabel.Text = 'Peso (kg):';
            app.FechaLabel = uilabel(app.GridLayout6);
            app.FechaLabel.Tag = 'labelFecha';
            app.FechaLabel.Text = 'Fecha:';
            app.textFieldPaciente = uilabel(app.GridLayout6);
            app.textFieldPaciente.Tag = 'textFieldPaciente';
            app.textFieldPaciente.Text = '';
            app.textFieldEdad = uilabel(app.GridLayout6);
            app.textFieldEdad.Tag = 'textFieldEdad';
            app.textFieldEdad.Text = '';
            app.textFieldPeso = uilabel(app.GridLayout6);
            app.textFieldPeso.Tag = 'textFieldPeso';
            app.textFieldPeso.Text = '';
            app.textFieldFecha = uilabel(app.GridLayout6);
            app.textFieldFecha.Tag = 'textFieldFecha';
            app.textFieldFecha.Text = '';
            app.Image = uiimage(app.GridLayout);
            app.Image.Tag = 'imageLogo';
            app.Image.ImageSource = 'foto.png';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_avatarAlfa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end