classdef GUI_graficas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        GridLayout5          matlab.ui.container.GridLayout
        BajadaAxes           matlab.ui.control.UIAxes
        SubidaAxes           matlab.ui.control.UIAxes
        GridLayout3          matlab.ui.container.GridLayout
        BienvenidoaSRSLabel  matlab.ui.control.Label
        GridLayout2          matlab.ui.container.GridLayout
        GrficasButtonGroup   matlab.ui.container.ButtonGroup
        TobilloButton        matlab.ui.control.Button
        RodillaButton        matlab.ui.control.Button
        CaderaButton         matlab.ui.control.Button
        GridLayout6          matlab.ui.container.GridLayout
        FechaTextField       matlab.ui.control.Label
        PesoTextField        matlab.ui.control.Label
        EdadTextField        matlab.ui.control.Label
        PacienteTextField    matlab.ui.control.Label
        FechaLabel           matlab.ui.control.Label
        PesokgLabel          matlab.ui.control.Label
        EdadLabel            matlab.ui.control.Label
        PacienteLabel        matlab.ui.control.Label
        GridLayout4          matlab.ui.container.GridLayout
        AvatarButton         matlab.ui.control.Button
        InformeButton        matlab.ui.control.Button
        ImageLogo            matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CaderaButton
        function CaderaButtonPushed(app, event)
            main;

            plot(app.SubidaAxes,cs);
            plot(app.BajadaAxes,cb,'r');
          
        end

        % Button pushed function: RodillaButton
        function RodillaButtonPushed(app, event)
            main;

            plot(app.SubidaAxes,rs);
            plot(app.BajadaAxes,rb,'r');

        end

        % Button pushed function: TobilloButton
        function TobilloButtonPushed(app, event)
            main;

            plot(app.SubidaAxes,ts);
            plot(app.BajadaAxes,tb,'r');
        end

        % Button pushed function: AvatarButton
        function AvatarButtonPushed(app, event)
            GUI_avatarAlfa;
            app.delete;
        end

        % Button pushed function: InformeButton
        function InformeButtonPushed(app, event)
            GUI_informe;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageLogo = uiimage(app.GridLayout);
            app.ImageLogo.Tag = 'imageLogo';
            app.ImageLogo.ImageSource = 'foto.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.InformeButton = uibutton(app.GridLayout4, 'push');
            app.InformeButton.ButtonPushedFcn = createCallbackFcn(app, @InformeButtonPushed, true);
            app.InformeButton.Tag = 'buttonInforme';
            app.InformeButton.Text = 'Informe';
            app.AvatarButton = uibutton(app.GridLayout4, 'push');
            app.AvatarButton.ButtonPushedFcn = createCallbackFcn(app, @AvatarButtonPushed, true);
            app.AvatarButton.Tag = 'buttonAvatar';
            app.AvatarButton.Text = 'Avatar';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.PacienteLabel = uilabel(app.GridLayout6);
            app.PacienteLabel.Tag = 'labelPaciente';
            app.PacienteLabel.Text = 'Paciente:';
            app.EdadLabel = uilabel(app.GridLayout6);
            app.EdadLabel.Tag = 'labelEdad';
            app.EdadLabel.Text = 'Edad:';
            app.PesokgLabel = uilabel(app.GridLayout6);
            app.PesokgLabel.Tag = 'labelPeso';
            app.PesokgLabel.Text = 'Peso (kg):';
            app.FechaLabel = uilabel(app.GridLayout6);
            app.FechaLabel.Tag = 'labelFecha';
            app.FechaLabel.Text = 'Fecha:';
            app.PacienteTextField = uilabel(app.GridLayout6);
            app.PacienteTextField.Tag = 'textFieldPaciente';
            app.PacienteTextField.Text = '';
            app.EdadTextField = uilabel(app.GridLayout6);
            app.EdadTextField.Tag = 'textFieldEdad';
            app.EdadTextField.Text = '';
            app.PesoTextField = uilabel(app.GridLayout6);
            app.PesoTextField.Tag = 'textFieldPeso';
            app.PesoTextField.Text = '';
            app.FechaTextField = uilabel(app.GridLayout6);
            app.FechaTextField.Tag = 'textFieldFecha';
            app.FechaTextField.Text = '';
            app.GrficasButtonGroup = uibuttongroup(app.GridLayout2);
            app.GrficasButtonGroup.Title = 'Gráficas';
            app.CaderaButton = uibutton(app.GrficasButtonGroup, 'push');
            app.CaderaButton.ButtonPushedFcn = createCallbackFcn(app, @CaderaButtonPushed, true);
            app.CaderaButton.Tag = 'buttonCadera';
            app.CaderaButton.Text = 'Cadera';
            app.RodillaButton = uibutton(app.GrficasButtonGroup, 'push');
            app.RodillaButton.ButtonPushedFcn = createCallbackFcn(app, @RodillaButtonPushed, true);
            app.RodillaButton.Tag = 'buttonRodilla';
            app.RodillaButton.Text = 'Rodilla';
            app.TobilloButton = uibutton(app.GrficasButtonGroup, 'push');
            app.TobilloButton.ButtonPushedFcn = createCallbackFcn(app, @TobilloButtonPushed, true);
            app.TobilloButton.Tag = 'buttonTobillo';
            app.TobilloButton.Text = 'Tobillo';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.SubidaAxes = uiaxes(app.GridLayout5);
            title(app.SubidaAxes, 'Subida')
            xlabel(app.SubidaAxes, 'Tiempo (ms)')
            ylabel(app.SubidaAxes, 'Ángulo (º)')
            zlabel(app.SubidaAxes, 'Z')
            app.SubidaAxes.Tag = 'axesSubida';
            app.BajadaAxes = uiaxes(app.GridLayout5);
            title(app.BajadaAxes, 'Bajada')
            xlabel(app.BajadaAxes, 'Tiempo (ms)')
            ylabel(app.BajadaAxes, 'Ángulo (º)')
            zlabel(app.BajadaAxes, 'Z')
            app.BajadaAxes.Tag = 'axesBajada';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_graficas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end