classdef GUI_informe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout5             matlab.ui.container.GridLayout
        Hyperlink               matlab.ui.control.Hyperlink
        PrametrosPanel          matlab.ui.container.Panel
        ngulosdeltobilloPanel   matlab.ui.container.Panel
        bajadaMAX_tobillo       matlab.ui.control.Label
        bajadaMIN_tobillo       matlab.ui.control.Label
        subidaMAX_tobillo       matlab.ui.control.Label
        subidaMIN_tobillo       matlab.ui.control.Label
        MAX_TobilloLabel        matlab.ui.control.Label
        MIN_TobilloLabel        matlab.ui.control.Label
        Bajada_tobilloLabel     matlab.ui.control.Label
        Subida_tobilloLabel     matlab.ui.control.Label
        ngulosdelarodillaPanel  matlab.ui.container.Panel
        bajadaMAX_rodilla       matlab.ui.control.Label
        bajadaMIN_rodilla       matlab.ui.control.Label
        subidaMAX_rodilla       matlab.ui.control.Label
        subidaMIN_rodilla       matlab.ui.control.Label
        MAX_RodillaLabel        matlab.ui.control.Label
        MIN_RodillaLabel        matlab.ui.control.Label
        Bajada_RodillaLabel     matlab.ui.control.Label
        Flexion_RodillaLabel    matlab.ui.control.Label
        ngulosdelacaderaPanel   matlab.ui.container.Panel
        bajadaMAX_cadera        matlab.ui.control.Label
        bajadaMIN_cadera        matlab.ui.control.Label
        subidaMAX_cadera        matlab.ui.control.Label
        subidaMIN_cadera        matlab.ui.control.Label
        MIN_CaderaLabel         matlab.ui.control.Label
        MAX_CaderaLabel         matlab.ui.control.Label
        Bajada_CaderaLabel      matlab.ui.control.Label
        Subida_CaderaLabel      matlab.ui.control.Label
        TiempoMAX_Label2        matlab.ui.control.Label
        TiempoMaximo_Label1     matlab.ui.control.Label
        TiempoMIN_Label2        matlab.ui.control.Label
        TiempoMinimo_Label1     matlab.ui.control.Label
        InformebiomecnicodelpacienteLabel  matlab.ui.control.Label
        GridLayout3             matlab.ui.container.GridLayout
        BienvenidoaSRSLabel     matlab.ui.control.Label
        GridLayout2             matlab.ui.container.GridLayout
        GridLayout6             matlab.ui.container.GridLayout
        FechaLabel2             matlab.ui.control.Label
        PesoLabel2              matlab.ui.control.Label
        EdadLabel2              matlab.ui.control.Label
        PacienteLabel2          matlab.ui.control.Label
        FechaLabel              matlab.ui.control.Label
        PesokgLabel             matlab.ui.control.Label
        EdadLabel               matlab.ui.control.Label
        PacienteLabel           matlab.ui.control.Label
        GridLayout7             matlab.ui.container.GridLayout
        GrficasButton           matlab.ui.control.Button
        AvatarButton            matlab.ui.control.Button
        ImageLogo               matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global nombre apellido edad peso;
            main;
            %load datos.mat nombre apellido edad peso;

            app.PacienteLabel2.Text = strcat(nombre," ",apellido);
            app.EdadLabel2.Text = int2str(edad);
            app.PesoLabel2.Text = num2str(peso);

            formato = 'dd/mm/yy';
            app.FechaLabel2.Text = datestr(now,formato);

            %cadera
            app.subidaMAX_cadera.Text = num2str(max(cs));
            app.subidaMIN_cadera.Text = num2str(min(cs));
            app.bajadaMAX_cadera.Text = num2str(max(cb));
            app.bajadaMIN_cadera.Text = num2str(min(cb));

            %rodilla
            app.subidaMAX_rodilla.Text = num2str(max(rs));
            app.subidaMIN_rodilla.Text = num2str(min(rs));
            app.bajadaMAX_rodilla.Text = num2str(max(rb));
            app.bajadaMIN_rodilla.Text = num2str(min(rb));

            %tobillo
            app.subidaMAX_tobillo.Text = num2str(max(ts));
            app.subidaMIN_tobillo.Text = num2str(min(ts));
            app.bajadaMAX_tobillo.Text = num2str(max(tb));
            app.bajadaMIN_tobillo.Text = num2str(min(tb));

            %tiempo minimo y maximo
            app.TiempoMAX_Label2.Text = strcat(num2str(1.5), " s");
            app.TiempoMIN_Label2.Text = strcat(num2str(0.96), " s");

        end

        % Button pushed function: GrficasButton
        function GrficasButtonPushed(app, event)
            global nombre apellido edad peso;
            GUI_graficas;
            app.delete;
        end

        % Button pushed function: AvatarButton
        function AvatarButtonPushed(app, event)
            global nombre apellido edad peso;
            GUI_avatarAlfa;
            app.delete;
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            global nombre apellido edad peso;
           
            datos = {'Tiempo máximo: ', app.TiempoMAX_Label2.Text...
                'Tiempo mínimo: ', app.TiempoMIN_Label2.Text...
                 'Ángulo máximo cadera subida: ', app.subidaMAX_cadera.Text...
                 'Ángulo máximo cadera bajada: ',app.bajadaMAX_cadera.Text...
                 'Ángulo mínimo cadera subida: ',app.subidaMIN_cadera.Text...
                 'Ángulo mínimo cadera bajada: ',app.bajadaMIN_cadera.Text...
 
%                  'Ángulo máximo rodilla subida: ',app.subidaMAX_rodilla.Text...
%                  'Ángulo máximo rodilla bajada: ',app.bajadaMAX_rodilla.Text...
%                  'Ángulo mínimo rodilla subida: ',app.subidaMIN_rodilla.Text...
%                  'Ángulo mínimo rodilla bajada: ',app.bajadaMIN_rodilla.Text...
 
%                  'Ángulo máximo tobillo subida: ',app.subidaMAX_tobillo.Text...
%                  'Ángulo máximo tobillo bajada: ',app.bajadaMAX_tobillo.Text...
%                  'Ángulo mínimo tobillo subida: ',app.subidaMIN_tobillo.Text...
%                  'Ángulo mínimo tobillo bajada: ',app.bajadaMIN_tobillo.Text...
                };
            nombreArchivo = strcat("informe", nombre, apellido, ".txt");
            fileID = fopen(nombreArchivo,'w');
            fprintf(fileID,'%s%s\n',datos{:});
            fclose(fileID);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageLogo = uiimage(app.GridLayout);
            app.ImageLogo.Tag = 'imageLogo';
            app.ImageLogo.ImageSource = 'foto.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.AvatarButton = uibutton(app.GridLayout7, 'push');
            app.AvatarButton.ButtonPushedFcn = createCallbackFcn(app, @AvatarButtonPushed, true);
            app.AvatarButton.Tag = 'buttonAvatar';
            app.AvatarButton.Text = 'Avatar';
            app.GrficasButton = uibutton(app.GridLayout7, 'push');
            app.GrficasButton.ButtonPushedFcn = createCallbackFcn(app, @GrficasButtonPushed, true);
            app.GrficasButton.Tag = 'buttonGráficas';
            app.GrficasButton.Text = 'Gráficas';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.PacienteLabel = uilabel(app.GridLayout6);
            app.PacienteLabel.Tag = 'labelPaciente';
            app.PacienteLabel.Text = 'Paciente:';
            app.EdadLabel = uilabel(app.GridLayout6);
            app.EdadLabel.Tag = 'labelEdad';
            app.EdadLabel.Text = 'Edad:';
            app.PesokgLabel = uilabel(app.GridLayout6);
            app.PesokgLabel.Tag = 'labelPeso';
            app.PesokgLabel.Text = 'Peso (kg):';
            app.FechaLabel = uilabel(app.GridLayout6);
            app.FechaLabel.Tag = 'labelFecha';
            app.FechaLabel.Text = 'Fecha:';
            app.PacienteLabel2 = uilabel(app.GridLayout6);
            app.PacienteLabel2.Tag = 'labelPaciente2';
            app.PacienteLabel2.Text = '';
            app.EdadLabel2 = uilabel(app.GridLayout6);
            app.EdadLabel2.Tag = 'labelEdad2';
            app.EdadLabel2.Text = '';
            app.PesoLabel2 = uilabel(app.GridLayout6);
            app.PesoLabel2.Tag = 'labelPeso2';
            app.PesoLabel2.Text = '';
            app.FechaLabel2 = uilabel(app.GridLayout6);
            app.FechaLabel2.Tag = 'labelFecha2';
            app.FechaLabel2.Text = '';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.InformebiomecnicodelpacienteLabel = uilabel(app.GridLayout5);
            app.InformebiomecnicodelpacienteLabel.Tag = 'labelInforme';
            app.InformebiomecnicodelpacienteLabel.Text = 'Informe biomecánico del paciente';
            app.PrametrosPanel = uipanel(app.GridLayout5);
            app.PrametrosPanel.Title = 'Párametros';
            app.TiempoMinimo_Label1 = uilabel(app.PrametrosPanel);
            app.TiempoMinimo_Label1.Tag = 'TiempoMinimo_Label1';
            app.TiempoMinimo_Label1.Text = 'Tiempo mínimo:';
            app.TiempoMIN_Label2 = uilabel(app.PrametrosPanel);
            app.TiempoMIN_Label2.Tag = 'TiempoMIN_Label2';
            app.TiempoMIN_Label2.Text = '';
            app.TiempoMaximo_Label1 = uilabel(app.PrametrosPanel);
            app.TiempoMaximo_Label1.Tag = 'TiempoMaximo_Label1';
            app.TiempoMaximo_Label1.Text = 'Tiempo máximo:';
            app.TiempoMAX_Label2 = uilabel(app.PrametrosPanel);
            app.TiempoMAX_Label2.Tag = 'TiempoMAX_Label2';
            app.TiempoMAX_Label2.Text = '';
            app.ngulosdelacaderaPanel = uipanel(app.PrametrosPanel);
            app.ngulosdelacaderaPanel.Title = 'Ángulos de la cadera';
            app.ngulosdelacaderaPanel.Tag = 'a_CaderaLabel';
            app.Subida_CaderaLabel = uilabel(app.ngulosdelacaderaPanel);
            app.Subida_CaderaLabel.Tag = 'Subida_CaderaLabel';
            app.Subida_CaderaLabel.Text = 'Subida';
            app.Bajada_CaderaLabel = uilabel(app.ngulosdelacaderaPanel);
            app.Bajada_CaderaLabel.Tag = 'Bajada_CaderaLabel';
            app.Bajada_CaderaLabel.Text = 'Bajada';
            app.MAX_CaderaLabel = uilabel(app.ngulosdelacaderaPanel);
            app.MAX_CaderaLabel.Tag = 'MAX_CaderaLabel';
            app.MAX_CaderaLabel.Text = 'MAX';
            app.MIN_CaderaLabel = uilabel(app.ngulosdelacaderaPanel);
            app.MIN_CaderaLabel.Tag = 'MIN_CaderaLabel';
            app.MIN_CaderaLabel.Text = 'MIN';
            app.subidaMIN_cadera = uilabel(app.ngulosdelacaderaPanel);
            app.subidaMIN_cadera.Tag = 'subidaMIN_cadera';
            app.subidaMIN_cadera.Text = '';
            app.subidaMAX_cadera = uilabel(app.ngulosdelacaderaPanel);
            app.subidaMAX_cadera.Tag = 'subidaMAX_cadera';
            app.subidaMAX_cadera.Text = '';
            app.bajadaMIN_cadera = uilabel(app.ngulosdelacaderaPanel);
            app.bajadaMIN_cadera.Tag = 'bajadaMIN_cadera';
            app.bajadaMIN_cadera.Text = '';
            app.bajadaMAX_cadera = uilabel(app.ngulosdelacaderaPanel);
            app.bajadaMAX_cadera.Tag = 'bajadaMAX_cadera';
            app.bajadaMAX_cadera.Text = '';
            app.ngulosdelarodillaPanel = uipanel(app.PrametrosPanel);
            app.ngulosdelarodillaPanel.Title = 'Ángulos de la rodilla';
            app.ngulosdelarodillaPanel.Tag = 'a_RodillaLabel';
            app.Flexion_RodillaLabel = uilabel(app.ngulosdelarodillaPanel);
            app.Flexion_RodillaLabel.Tag = 'Subida_RodillaLabel';
            app.Flexion_RodillaLabel.Text = 'Subida';
            app.Bajada_RodillaLabel = uilabel(app.ngulosdelarodillaPanel);
            app.Bajada_RodillaLabel.Tag = 'Bajada_RodillaLabel';
            app.Bajada_RodillaLabel.Text = 'Bajada';
            app.MIN_RodillaLabel = uilabel(app.ngulosdelarodillaPanel);
            app.MIN_RodillaLabel.Tag = 'MIN_RodillaLabel';
            app.MIN_RodillaLabel.Text = 'MIN';
            app.MAX_RodillaLabel = uilabel(app.ngulosdelarodillaPanel);
            app.MAX_RodillaLabel.Tag = 'MAX_RodillaLabel';
            app.MAX_RodillaLabel.Text = 'MAX';
            app.subidaMIN_rodilla = uilabel(app.ngulosdelarodillaPanel);
            app.subidaMIN_rodilla.Tag = 'subidaMIN_rodilla';
            app.subidaMIN_rodilla.Text = '';
            app.subidaMAX_rodilla = uilabel(app.ngulosdelarodillaPanel);
            app.subidaMAX_rodilla.Tag = 'subidaMAX_rodilla';
            app.subidaMAX_rodilla.Text = '';
            app.bajadaMIN_rodilla = uilabel(app.ngulosdelarodillaPanel);
            app.bajadaMIN_rodilla.Tag = 'bajadaMIN_rodilla';
            app.bajadaMIN_rodilla.Text = '';
            app.bajadaMAX_rodilla = uilabel(app.ngulosdelarodillaPanel);
            app.bajadaMAX_rodilla.Tag = 'bajadaMAX_rodilla';
            app.bajadaMAX_rodilla.Text = '';
            app.ngulosdeltobilloPanel = uipanel(app.PrametrosPanel);
            app.ngulosdeltobilloPanel.Title = 'Ángulos del tobillo';
            app.ngulosdeltobilloPanel.Tag = 'a_TobilloLabel';
            app.Subida_tobilloLabel = uilabel(app.ngulosdeltobilloPanel);
            app.Subida_tobilloLabel.Tag = 'Subida_tobilloLabel';
            app.Subida_tobilloLabel.Text = 'Subida';
            app.Bajada_tobilloLabel = uilabel(app.ngulosdeltobilloPanel);
            app.Bajada_tobilloLabel.Tag = 'Bajada_tobilloLabel';
            app.Bajada_tobilloLabel.Text = 'Bajada ';
            app.MIN_TobilloLabel = uilabel(app.ngulosdeltobilloPanel);
            app.MIN_TobilloLabel.Tag = 'MIN_TobilloLabel';
            app.MIN_TobilloLabel.Text = 'MIN';
            app.MAX_TobilloLabel = uilabel(app.ngulosdeltobilloPanel);
            app.MAX_TobilloLabel.Tag = 'MAX_TobilloLabel';
            app.MAX_TobilloLabel.Text = 'MAX';
            app.subidaMIN_tobillo = uilabel(app.ngulosdeltobilloPanel);
            app.subidaMIN_tobillo.Tag = 'subidaMIN_tobillo';
            app.subidaMIN_tobillo.Text = '';
            app.subidaMAX_tobillo = uilabel(app.ngulosdeltobilloPanel);
            app.subidaMAX_tobillo.Tag = 'subidaMAX_tobillo';
            app.subidaMAX_tobillo.Text = '';
            app.bajadaMIN_tobillo = uilabel(app.ngulosdeltobilloPanel);
            app.bajadaMIN_tobillo.Tag = 'bajadaMIN_tobillo';
            app.bajadaMIN_tobillo.Text = '';
            app.bajadaMAX_tobillo = uilabel(app.ngulosdeltobilloPanel);
            app.bajadaMAX_tobillo.Tag = 'bajadaMAX_tobillo';
            app.bajadaMAX_tobillo.Text = '';
            app.Hyperlink = uihyperlink(app.GridLayout5);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Tag = 'hypDescargar';
            app.Hyperlink.Text = 'Descargar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_informe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end