classdef GUI_inicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout4           matlab.ui.container.GridLayout
        AvisoLabel            matlab.ui.control.Label
        GridLayout5           matlab.ui.container.GridLayout
        PesokgEditField       matlab.ui.control.NumericEditField
        PesokgEditFieldLabel  matlab.ui.control.Label
        EdadEditField         matlab.ui.control.NumericEditField
        EdadEditFieldLabel    matlab.ui.control.Label
        ApellidosEditField    matlab.ui.control.EditField
        ApellidosLabel        matlab.ui.control.Label
        NombreEditField       matlab.ui.control.EditField
        NombreEditFieldLabel  matlab.ui.control.Label
        GridLayout3           matlab.ui.container.GridLayout
        PorfavorrellenelossiguientescamposLabel  matlab.ui.control.Label
        BienvenidoaSRSLabel   matlab.ui.control.Label
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout6           matlab.ui.container.GridLayout
        GraficasButton        matlab.ui.control.Button
        VideoButton           matlab.ui.control.Button
        LabelInfo             matlab.ui.control.Label
        ImageLogo             matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficasButton
        function GraficasButtonPushed(app, event)
            global nombre apellidos edad peso;

            nombre = app.NombreEditField.Value;
            apellidos = app.ApellidosEditField.Value;
            edad = app.EdadEditField.Value;
            peso = app.PesokgEditField.Value;

            caracteres_validos = '^[a-zA-Z\s]*$';

            if isempty(nombre) || isempty(apellidos)
               app.AvisoLabel.Text = 'Para continuar asegúrese de que todos los campos están rellenos';
            elseif edad <= 0 
                app.AvisoLabel.Text = 'Asegúrese de que la edad introducida no es menor o igual a 0';
            elseif edad >= 150 
                app.AvisoLabel.Text = 'Asegúrese de que la edad introducida no es mayor o igual a 150';    
            elseif peso <= 0
               app.AvisoLabel.Text = 'Asegúrese de que el peso introducido no es menor o igual a 0';
            elseif peso >= 300
               app.AvisoLabel.Text = 'Asegúrese de que el peso introducido no es mayor o igual a 300';
            elseif (size(regexp(nombre,caracteres_validos,'match')) == 0)
               app.AvisoLabel.Text = 'Asegúrese de que el nombre no contenga números o caracteres especiales';
            elseif (size(regexp(apellidos,caracteres_validos,'match')) == 0)
               app.AvisoLabel.Text = 'Asegúrese de que el apellido no contenga números o caracteres especiales';
            else    
               %save datos.mat nombre apellido edad peso;
               GUI_graficas_new4;
               app.delete;
            end
        end

        % Button pushed function: VideoButton
        function VideoButtonPushed(app, event)
            GUI_Video;
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageLogo = uiimage(app.GridLayout);
            app.ImageLogo.Tag = 'imageLogo';
            app.ImageLogo.ImageSource = 'foto.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LabelInfo = uilabel(app.GridLayout2);
            app.LabelInfo.Tag = 'labelInfo';
            app.LabelInfo.Text = 'Tras rellenar las campos, elija una opción de las que se muestran en el menú inferior';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.VideoButton = uibutton(app.GridLayout6, 'push');
            app.VideoButton.ButtonPushedFcn = createCallbackFcn(app, @VideoButtonPushed, true);
            app.VideoButton.Tag = 'buttonVideo';
            app.VideoButton.Text = 'Vídeo';
            app.GraficasButton = uibutton(app.GridLayout6, 'push');
            app.GraficasButton.ButtonPushedFcn = createCallbackFcn(app, @GraficasButtonPushed, true);
            app.GraficasButton.Tag = 'buttonGráficas';
            app.GraficasButton.Text = 'Gráficas';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            app.PorfavorrellenelossiguientescamposLabel = uilabel(app.GridLayout3);
            app.PorfavorrellenelossiguientescamposLabel.Tag = 'labelCampos';
            app.PorfavorrellenelossiguientescamposLabel.Text = 'Por favor, rellene los siguientes campos';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.NombreEditFieldLabel = uilabel(app.GridLayout5);
            app.NombreEditFieldLabel.Tag = 'labelNombre';
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.GridLayout5, 'text');
            app.NombreEditField.Tag = 'textFieldNombre';
            app.ApellidosLabel = uilabel(app.GridLayout5);
            app.ApellidosLabel.Tag = 'labelApellidos';
            app.ApellidosLabel.Text = 'Apellidos';
            app.ApellidosEditField = uieditfield(app.GridLayout5, 'text');
            app.ApellidosEditField.Tag = 'labelApellidos';
            app.EdadEditFieldLabel = uilabel(app.GridLayout5);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.GridLayout5, 'numeric');
            app.EdadEditField.Limits = [0 150];
            app.EdadEditField.ValueDisplayFormat = '%.0f';
            app.EdadEditField.Tag = 'NumericFieldEdad';
            app.PesokgEditFieldLabel = uilabel(app.GridLayout5);
            app.PesokgEditFieldLabel.Text = 'Peso (kg)';
            app.PesokgEditField = uieditfield(app.GridLayout5, 'numeric');
            app.PesokgEditField.Limits = [0 300];
            app.PesokgEditField.Tag = 'NumericFieldPeso';
            app.AvisoLabel = uilabel(app.GridLayout4);
            app.AvisoLabel.Tag = 'labelAviso';
            app.AvisoLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_inicio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end